classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SamplingPanel               matlab.ui.container.Panel
        SampleandReconstructButton  matlab.ui.control.Button
        PointsSlider                matlab.ui.control.Slider
        PointsSliderLabel           matlab.ui.control.Label
        LinesSlider                 matlab.ui.control.Slider
        LinesSliderLabel            matlab.ui.control.Label
        AcquisitionTrajectoryButtonGroup  matlab.ui.container.ButtonGroup
        RadialButton                matlab.ui.control.RadioButton
        CartesianButton             matlab.ui.control.RadioButton
        Image3                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        ImageSelectionButtonGroup   matlab.ui.container.ButtonGroup
        GetImageButton              matlab.ui.control.Button
        PhantomSizeEditField        matlab.ui.control.NumericEditField
        PhantomSizeEditFieldLabel   matlab.ui.control.Label
        BrainScanButton             matlab.ui.control.RadioButton
        TestPhantom2Button          matlab.ui.control.RadioButton
        TestPhantom1Button          matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
            if(app.TestPhantom1Button.Value)
                N = app.PhantomSizeEditField.Value;
                canvasSize = [N, N];
                mat = zeros(canvasSize,'uint8');
                
                % Calculating the center of the canvas
                centerPoint = [canvasSize(1)/2, canvasSize(2)/2];
                xc = centerPoint(1);
                yc = centerPoint(2);
            
                % Set radius of the background circle as 40% of the canvas's width
                radius = ceil(0.4 * canvasSize(1));
            
                %dimensions of rectangle (NOTE: change later for user entry)
                width = 40;
                height = 150;
                
                %coordinates of upper left corner of rectangle
                xr = xc - (width/2);
                yr = yc - (height/2);
            
                % Draw the background circle on the canvas with a specified color
                IM = insertShape(mat,"filled-circle",[xc yc radius],Color='white');
                IM2 = insertShape(IM,"filled-rectangle",[xr yr width height],Color='white');
                app.Image2.ImageSource = IM2;
            end
            if(app.TestPhantom2Button.Value)
                N = app.PhantomSizeEditField.Value;
                canvasSize = [N, N];
                mat = zeros(canvasSize,'uint8');

                % Calculating the center of the canvas
                centerPoint = [canvasSize(1)/2, canvasSize(2)/2];
                xc = centerPoint(1);
                yc = centerPoint(2);
            
                % Set radius of the background circle as 40% of the canvas's width
                radius = ceil(0.4 * canvasSize(1));
                % Draw the background circle on the canvas with a specified color
                IM = insertShape(mat,"filled-circle",[xc yc radius],Color='white');
                
                % x-coordinates and radii for foreground circle series
                innerCircleDistance = ceil(radius/5.4);
                
                c1_x = centerPoint(2)- floor(4.5 * innerCircleDistance);
                c1_r = ceil( min(canvasSize)/55);
            
                c2_x = centerPoint(2)- 3 * innerCircleDistance;
                c2_r = floor(1.52 * c1_r);
            
                c3_x = centerPoint(2)- 1 * innerCircleDistance;
                c3_r = floor(1.52 * c2_r);
            
                c4_x = centerPoint(2) + innerCircleDistance;
                c4_r = floor(1.52 * c3_r);
            
                c5_x = centerPoint(2) + floor(3.5 * innerCircleDistance);
                c5_r = floor(1.52 * c4_r);
            
                % Draw series of circles of increasing size on top of background circle
                IM2 = insertShape(IM,"filled-circle",[c1_x yc c1_r],Color='white');
                IM3 = insertShape(IM2,"filled-circle",[c2_x yc c2_r],Color='white');
                IM4 = insertShape(IM3,"filled-circle",[c3_x yc c3_r],Color='white');
                IM5 = insertShape(IM4,"filled-circle",[c4_x yc c4_r],Color='white');
                IM6 = insertShape(IM5,"filled-circle",[c5_x yc c5_r],Color='white');

                app.Image2.ImageSource = IM6;
            end
            if(app.BrainScanButton.Value)
                app.Image2.ImageSource = "brain.jpg";
            end
        end

        % Button pushed function: SampleandReconstructButton
        function SampleandReconstructButtonPushed(app, event)
            if(app.CartesianButton.Value)
                numberOfLines = app.LinesSlider.Value;
                pointsPerLine = app.PointsSlider.Value;
            
                % Generate Cartesian grid
                [x, y] = meshgrid(-numberOfLines:pointsPerLine, -numberOfLines:pointsPerLine);
            
                % Calculate radial distance from the center
                radialDistance = sqrt(x.^2 + y.^2);
            
                % Creating a circular mask with radius 15
                circularMask = (radialDistance < 15);
            
                % Performing Fourier Transform
                fftResult = fftshift(fft2(circularMask));
            
                % Computing the log magnitude spectrum
                logMagnitudeSpectrum = log(1 + abs(fftResult));
            
                % Visualize Sampled K-Space for Radial
                %imshow(im2uint8(logMagnitudeSpectrum / max(logMagnitudeSpectrum(:))), [], 'parent', app.SampledKSpaceImage);
            
                numLines = numberOfLines;
                numPoints = pointsPerLine;
                % Defining ranges for cropping
                startX = int64(640 - (numLines * 2.5));
                startY = int64(640 - (numPoints * 2.5));
                if startX == 0
                    startX = 1;
                end
                if startY == 0
                    startY = 1;
                end
                endX = int64(640 + (numLines * 2.5));
                endY = int64(640 + (numPoints * 2.5));
            
                %disp(startX);
                %disp(startY);
                %disp(endX);
                %disp(endY);
                
                if(app.BrainScanButton.Value)
                    imData = imread('brain.jpg'); 
                else
                    imData = app.Image2.ImageSource;
                end

            
                % Fourier transform with zero padding
                fourierTransform = fft2(imData, 1280, 1280);
                shiftedKSpace = fftshift(fourierTransform);
                
                %app.Image3.ImageSource = shiftedKSpace;
                

                % Cropping the k-space
                croppedKSpace = shiftedKSpace(startX:endX, startY:endY);
            
                % Inverse Fourier transform
                reconstruction = ifft2(croppedKSpace);
            
                % Cropping the reconstruction to specified size
                croppedReconstruction = reconstruction(1:numLines, 1:numPoints);
            
                % Scaling and resizing the image
                absScaled = abs(croppedReconstruction);
                stretchedImage = imresize(absScaled, [256, 256]);
            
                % Generating Sampled K-Space image for Cartesian sampling
                targetSize = [numLines * 5, numPoints * 5];
                win1 = centerCropWindow2d(size(shiftedKSpace), targetSize);
                B1 = imcrop(abs(log(shiftedKSpace)), win1);
                B1 = imresize(B1, [256, 256]);
                %imshow(B1, [], 'parent', app.SampledKSpaceImage);
                idiff = stretchedImage;
                % Display the reconstructed image for Cartesian

                %app.Image.ImageSource = idiff;
            end
            %{
            if(app.RadialButton.Value)

            end
            %}
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageSelectionButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageSelectionButtonGroup.Title = 'Image Selection';
            app.TestPhantom1Button = uiradiobutton(app.ImageSelectionButtonGroup);
            app.TestPhantom1Button.Text = 'Test Phantom 1';
            app.TestPhantom1Button.Value = true;
            app.TestPhantom2Button = uiradiobutton(app.ImageSelectionButtonGroup);
            app.TestPhantom2Button.Text = 'Test Phantom 2';
            app.BrainScanButton = uiradiobutton(app.ImageSelectionButtonGroup);
            app.BrainScanButton.Text = 'Brain Scan';
            app.PhantomSizeEditFieldLabel = uilabel(app.ImageSelectionButtonGroup);
            app.PhantomSizeEditFieldLabel.Text = 'Phantom Size';
            app.PhantomSizeEditField = uieditfield(app.ImageSelectionButtonGroup, 'numeric');
            app.PhantomSizeEditField.Value = 300;
            app.GetImageButton = uibutton(app.ImageSelectionButtonGroup, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = 'Get Image';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.SamplingPanel = uipanel(app.UIFigure);
            app.SamplingPanel.Title = 'Sampling';
            app.AcquisitionTrajectoryButtonGroup = uibuttongroup(app.SamplingPanel);
            app.AcquisitionTrajectoryButtonGroup.Title = 'Acquisition Trajectory';
            app.CartesianButton = uiradiobutton(app.AcquisitionTrajectoryButtonGroup);
            app.CartesianButton.Text = 'Cartesian';
            app.CartesianButton.Value = true;
            app.RadialButton = uiradiobutton(app.AcquisitionTrajectoryButtonGroup);
            app.RadialButton.Text = 'Radial';
            app.LinesSliderLabel = uilabel(app.SamplingPanel);
            app.LinesSliderLabel.Text = 'Lines';
            app.LinesSlider = uislider(app.SamplingPanel);
            app.LinesSlider.Limits = [16 200];
            app.LinesSlider.Value = 16;
            app.PointsSliderLabel = uilabel(app.SamplingPanel);
            app.PointsSliderLabel.Text = 'Points';
            app.PointsSlider = uislider(app.SamplingPanel);
            app.PointsSlider.Limits = [16 200];
            app.PointsSlider.Value = 16;
            app.SampleandReconstructButton = uibutton(app.SamplingPanel, 'push');
            app.SampleandReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @SampleandReconstructButtonPushed, true);
            app.SampleandReconstructButton.Text = 'Sample and Reconstruct';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end